classdef GIU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ExtrapolarButton          matlab.ui.control.Button
        HbA1cLabel                matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        RegistrarButton           matlab.ui.control.Button
        GlucometraEditField       matlab.ui.control.NumericEditField
        GlucometraEditFieldLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data;
    end
    
    methods (Access = private)
        
        function preprocess(app)

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("Data.mat","Data")
            app.Data = Data;
            preprocess(app);
        end

        % Callback function
        function ButtonPushed(app, event)
           
        end

        % Button pushed function: RegistrarButton
        function RegistrarButtonPushed2(app, event)
           
gluc = app.GlucometraEditField.Value;
glucoseData = getappdata(0, 'glucoseData');

if isempty(glucoseData)
    glucoseData = [];
end

glucoseData = [glucoseData; gluc];

x = linspace(1, length(glucoseData), length(glucoseData));
setappdata(0, 'glucoseData', glucoseData);
cla(app.UIAxes);
inRangeIdx = glucoseData >= 100 & glucoseData <= 125;
outRangeIdx = ~inRangeIdx;
scatter(app.UIAxes, x(inRangeIdx), glucoseData(inRangeIdx), 'MarkerFaceColor', 'b', 'DisplayName', 'Dentro del rango');
hold(app.UIAxes, 'on');
scatter(app.UIAxes, x(outRangeIdx), glucoseData(outRangeIdx), 'MarkerFaceColor', 'r', 'DisplayName', 'Fuera del rango');
plot(app.UIAxes, x, glucoseData, 'LineWidth', 2, 'Color', 'k', 'DisplayName', 'Glucosa');
avg = mean(glucoseData);
rango = sum(inRangeIdx);
tiempor = rango / length(glucoseData);
app.Label.Text = sprintf('%.2f', tiempor);
legend(app.UIAxes);

hold(app.UIAxes, 'off');
 % Calcular el eje X para la gráfica
            x = linspace(1, length(glucoseData), length(glucoseData));
            
            % Realizar ajuste de regresión lineal
            p = polyfit(x,glucoseData,1); % Ajuste lineal
            
            % Predecir los próximos 10 puntos
            future_x = (length(glucoseData) + 1):(length(glucoseData) + 30);
            future_y = polyval(p, future_x);
            
            % Actualizar la gráfica
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, future_x, future_y, '--g', 'LineWidth', 2, 'DisplayName', 'Extrapolación');
            legend(app.UIAxes);
            hold(app.UIAxes, 'off');


        end

        % Button pushed function: ExtrapolarButton
        function ExtrapolarButtonPushed(app, event)
            
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GlucometraEditFieldLabel = uilabel(app.UIFigure);
            app.GlucometraEditFieldLabel.Text = 'Glucometría';
            app.GlucometraEditField = uieditfield(app.UIFigure, 'numeric');
            app.RegistrarButton = uibutton(app.UIFigure, 'push');
            app.RegistrarButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrarButtonPushed2, true);
            app.RegistrarButton.Text = 'Registrar';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.HbA1cLabel = uilabel(app.UIFigure);
            app.HbA1cLabel.Text = 'HbA1c';
            app.ExtrapolarButton = uibutton(app.UIFigure, 'push');
            app.ExtrapolarButton.ButtonPushedFcn = createCallbackFcn(app, @ExtrapolarButtonPushed, true);
            app.ExtrapolarButton.Text = 'Extrapolar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GIU

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end